classdef SwingDAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ClearFigureButton           matlab.ui.control.Button
        LoadCell6CheckBox           matlab.ui.control.CheckBox
        LoadCell5CheckBox           matlab.ui.control.CheckBox
        LoadCell4CheckBox           matlab.ui.control.CheckBox
        LoadCell3CheckBox           matlab.ui.control.CheckBox
        LoadCell2CheckBox           matlab.ui.control.CheckBox
        LoadCell1CheckBox           matlab.ui.control.CheckBox
        OrientationZCheckBox_2      matlab.ui.control.CheckBox
        OrientationYCheckBox_2      matlab.ui.control.CheckBox
        OrientationXCheckBox        matlab.ui.control.CheckBox
        AngularVelZCheckBox         matlab.ui.control.CheckBox
        AngularVelYCheckBox         matlab.ui.control.CheckBox
        AngularVelXCheckBox         matlab.ui.control.CheckBox
        AccelZCheckBox              matlab.ui.control.CheckBox
        AccelYCheckBox              matlab.ui.control.CheckBox
        AccelXCheckBox              matlab.ui.control.CheckBox
        DisconnectButton            matlab.ui.control.Button
        BrowseButton                matlab.ui.control.Button
        FilenametosaveacquireddataEditField  matlab.ui.control.EditField
        FilenametosaveacquireddataEditFieldLabel  matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        EndDAQButton                matlab.ui.control.Button
        StartDAQButton              matlab.ui.control.Button
        COMPortforArduinoEditField  matlab.ui.control.EditField
        COMPortforArduinoEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myArduino % serial port connected to Arduino
        checkBoxValues % boolean values of checkboxes
    end

    properties (Constant, Access = public)
        plotBlockSize = 10;
        numChannels = 15;
    end

    events
        AcquiredDataBlock
    end

    methods (Access = public)
        function channelsToPlot = getChannelsToPlot(app)
            channelsToPlot = app.checkBoxValues;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This callback is to be called during initialization of the
            % app object; this function is created by clicking on the add
            % Callback button in the editor and selecting SwingDAQ as the
            % component
            app.checkBoxValues = false(1,app.numChannels);
        end

        % Button pushed function: StartDAQButton
        function StartDAQButtonPushed(app, event)
            app.myArduino.UserData = struct("Data",[],"Count",0);
            configureCallback(app.myArduino,"terminator",...
                            @(src,evt)(readArduino(src,evt,app)));
        end

        % Button pushed function: EndDAQButton
        function EndDAQButtonPushed(app, event)
            configureCallback(app.myArduino, "off");
            acquiredData = app.myArduino.UserData.Data;
            filename = app.FilenametosaveacquireddataEditField.Value;
            save(filename,"acquiredData");
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            comport = app.COMPortforArduinoEditField.Value;
            try
                app.myArduino = serialport(comport, 57600);
            catch
                msgbox("Invalid COM port name.")
                return
            end
            configureTerminator(app.myArduino,"CR/LF");

            addlistener(app,'AcquiredDataBlock',...
                @(src,evt)plotAcquiredData(src,evt,...
                                           app.myArduino,app.UIAxes));
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uiputfile("*.mat");
            app.FilenametosaveacquireddataEditField.Value = ...
                fullfile(path,file);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.myArduino.delete;
        end

        % Value changed function: AccelXCheckBox
        function AccelXCheckBoxValueChanged(app, event)
            value = app.AccelXCheckBox.Value;
            app.checkBoxValues(1) = value;
        end

        % Value changed function: AccelYCheckBox
        function AccelYCheckBoxValueChanged(app, event)
            value = app.AccelYCheckBox.Value;
            app.checkBoxValues(2) = value;
        end

        % Value changed function: AccelZCheckBox
        function AccelZCheckBoxValueChanged(app, event)
            value = app.AccelZCheckBox.Value;
            app.checkBoxValues(3) = value;
        end

        % Value changed function: LoadCell1CheckBox
        function LoadCell1CheckBoxValueChanged(app, event)
            value = app.LoadCell1CheckBox.Value;
            app.checkBoxValues(10) = value;
        end

        % Value changed function: LoadCell2CheckBox
        function LoadCell2CheckBoxValueChanged(app, event)
            value = app.LoadCell2CheckBox.Value;
            app.checkBoxValues(11) = value;
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: LoadCell3CheckBox
        function LoadCell3CheckBoxValueChanged(app, event)
            value = app.LoadCell3CheckBox.Value;
            app.checkBoxValues(12) = value;
        end

        % Value changed function: LoadCell4CheckBox
        function LoadCell4CheckBoxValueChanged(app, event)
            value = app.LoadCell4CheckBox.Value;
            app.checkBoxValues(13) = value;
        end

        % Value changed function: LoadCell5CheckBox
        function LoadCell5CheckBoxValueChanged(app, event)
            value = app.LoadCell5CheckBox.Value;
            app.checkBoxValues(14) = value;
        end

        % Value changed function: LoadCell6CheckBox
        function LoadCell6CheckBoxValueChanged(app, event)
            value = app.LoadCell6CheckBox.Value;
            app.checkBoxValues(15) = value;
        end

        % Value changed function: AngularVelXCheckBox
        function AngularVelXCheckBoxValueChanged(app, event)
            value = app.AngularVelXCheckBox.Value;
            app.checkBoxValues(4) = value;
        end

        % Value changed function: AngularVelYCheckBox
        function AngularVelYCheckBoxValueChanged(app, event)
            value = app.AngularVelYCheckBox.Value;
            app.checkBoxValues(5) = value;
        end

        % Value changed function: AngularVelZCheckBox
        function AngularVelZCheckBoxValueChanged(app, event)
            value = app.AngularVelZCheckBox.Value;
            app.checkBoxValues(6) = value;
        end

        % Value changed function: OrientationXCheckBox
        function OrientationXCheckBoxValueChanged(app, event)
            value = app.OrientationXCheckBox.Value;
            app.checkBoxValues(7) = value;
        end

        % Value changed function: OrientationYCheckBox_2
        function OrientationYCheckBox_2ValueChanged(app, event)
            value = app.OrientationYCheckBox_2.Value;
            app.checkBoxValues(8) = value;
        end

        % Value changed function: OrientationZCheckBox_2
        function OrientationZCheckBox_2ValueChanged(app, event)
            value = app.OrientationZCheckBox_2.Value;
            app.checkBoxValues(9) = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acquired Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.COMPortforArduinoEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortforArduinoEditFieldLabel.Text = 'COM Port for Arduino';
            app.COMPortforArduinoEditField = uieditfield(app.UIFigure, 'text');
            app.StartDAQButton = uibutton(app.UIFigure, 'push');
            app.StartDAQButton.ButtonPushedFcn = createCallbackFcn(app, @StartDAQButtonPushed, true);
            app.StartDAQButton.Text = 'Start DAQ';
            app.EndDAQButton = uibutton(app.UIFigure, 'push');
            app.EndDAQButton.ButtonPushedFcn = createCallbackFcn(app, @EndDAQButtonPushed, true);
            app.EndDAQButton.Text = 'End DAQ';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.FilenametosaveacquireddataEditFieldLabel = uilabel(app.UIFigure);
            app.FilenametosaveacquireddataEditFieldLabel.Text = 'Filename to  save acquired data';
            app.FilenametosaveacquireddataEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.AccelXCheckBox = uicheckbox(app.UIFigure);
            app.AccelXCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccelXCheckBoxValueChanged, true);
            app.AccelXCheckBox.Text = 'AccelX';
            app.AccelYCheckBox = uicheckbox(app.UIFigure);
            app.AccelYCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccelYCheckBoxValueChanged, true);
            app.AccelYCheckBox.Text = 'AccelY';
            app.AccelZCheckBox = uicheckbox(app.UIFigure);
            app.AccelZCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccelZCheckBoxValueChanged, true);
            app.AccelZCheckBox.Text = 'AccelZ';
            app.AngularVelXCheckBox = uicheckbox(app.UIFigure);
            app.AngularVelXCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelXCheckBoxValueChanged, true);
            app.AngularVelXCheckBox.Text = 'Angular Vel X';
            app.AngularVelYCheckBox = uicheckbox(app.UIFigure);
            app.AngularVelYCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelYCheckBoxValueChanged, true);
            app.AngularVelYCheckBox.Text = 'Angular Vel Y';
            app.AngularVelZCheckBox = uicheckbox(app.UIFigure);
            app.AngularVelZCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelZCheckBoxValueChanged, true);
            app.AngularVelZCheckBox.Text = 'Angular Vel Z';
            app.OrientationXCheckBox = uicheckbox(app.UIFigure);
            app.OrientationXCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientationXCheckBoxValueChanged, true);
            app.OrientationXCheckBox.Text = 'Orientation X';
            app.OrientationYCheckBox_2 = uicheckbox(app.UIFigure);
            app.OrientationYCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OrientationYCheckBox_2ValueChanged, true);
            app.OrientationYCheckBox_2.Text = 'Orientation Y';
            app.OrientationZCheckBox_2 = uicheckbox(app.UIFigure);
            app.OrientationZCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OrientationZCheckBox_2ValueChanged, true);
            app.OrientationZCheckBox_2.Text = 'Orientation Z';
            app.LoadCell1CheckBox = uicheckbox(app.UIFigure);
            app.LoadCell1CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCell1CheckBoxValueChanged, true);
            app.LoadCell1CheckBox.Text = 'Load Cell 1';
            app.LoadCell2CheckBox = uicheckbox(app.UIFigure);
            app.LoadCell2CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCell2CheckBoxValueChanged, true);
            app.LoadCell2CheckBox.Text = 'Load Cell 2';
            app.LoadCell3CheckBox = uicheckbox(app.UIFigure);
            app.LoadCell3CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCell3CheckBoxValueChanged, true);
            app.LoadCell3CheckBox.Text = 'Load Cell 3';
            app.LoadCell4CheckBox = uicheckbox(app.UIFigure);
            app.LoadCell4CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCell4CheckBoxValueChanged, true);
            app.LoadCell4CheckBox.Text = 'Load Cell 4';
            app.LoadCell5CheckBox = uicheckbox(app.UIFigure);
            app.LoadCell5CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCell5CheckBoxValueChanged, true);
            app.LoadCell5CheckBox.Text = 'Load Cell 5';
            app.LoadCell6CheckBox = uicheckbox(app.UIFigure);
            app.LoadCell6CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCell6CheckBoxValueChanged, true);
            app.LoadCell6CheckBox.Text = 'Load Cell 6';
            app.ClearFigureButton = uibutton(app.UIFigure, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwingDAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end